classdef deneme2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image2                matlab.ui.control.Image
        Image                 matlab.ui.control.Image
        TanLabel              matlab.ui.control.Label
        SnflandrButton        matlab.ui.control.Button
        ModelSeDropDown       matlab.ui.control.DropDown
        ModelSeDropDownLabel  matlab.ui.control.Label
        FotorafSeButton       matlab.ui.control.Button
        liveImg               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        isFile;filePath; % Description
        detector;
    end
    
    methods (Access = public)
        
        function  setSelection(app,s1)
            app.isFile = s1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: FotorafSeButton
        function FotorafSeButtonPushed(app, event)
              setSelection(app,true);
                [filename, pathname] = uigetfile(...    
                { '*.jpg; *.JPG; *.jpeg; *.JPEG; *.img; *.IMG; *.tif; *.TIF; *.tiff; *.TIFF; *.png; *.PNG', 'Supported Files (*.jpg, *.img, *.tiff, *.png)';
    '*.jpg', 'jpg Files (*.jpg)';
    '*.JPG', 'JPG Files (*.JPG)';
    '*.jpeg', 'jpeg Files (*.jpeg)';
    '*.JPEG', 'JPEG Files (*.JPEG)';
    '*.img', 'img Files (*.img)';
    '*.IMG', 'IMG Files (*.IMG)';
    '*.tif', 'tif Files (*.tif)';
    '*.TIF', 'TIF Files (*.TIF)';
    '*.tiff', 'tiff Files (*.tiff)';
    '*.TIFF', 'TIFF Files (*.TIFF)';
    '*.png', 'PNG Files (*.png)';
    '*.PNG', 'PNG Files (*.PNG)'});
                app.filePath = fullfile(pathname,filename);
                image(app.liveImg, imread(app.filePath));
        end

        % Button pushed function: SnflandrButton
        function SnflandrButtonPushed(app, event)

    selectedModel = app.ModelSeDropDown.Value;
    switch selectedModel
        case 'GoogleNet-Adam'
            % Load or create GoogleNet model
            loadedModel = load('C:\Users\İREM\Desktop\derin_ogrenme_arttirilmis\Googleadam2.mat'); % Update with your actual file path
            net = loadedModel.net; % Make sure to use the correct variable name
            inputSize = [224, 224];
         case 'GoogleNet-Sgdm'
            % Load or create GoogleNet model
            loadedModel = load('C:\Users\İREM\Desktop\derin_ogrenme_arttirilmis\Googlesgdm4.mat'); % Update with your actual file path
            net = loadedModel.net; % Make sure to use the correct variable name
            inputSize = [224, 224];
         case 'GoogleNet-Rmsprop'
            % Load or create GoogleNet model
            loadedModel = load('C:\Users\İREM\Desktop\derin_ogrenme_arttirilmis\Googlermsproparttirilmis.mat'); % Update with your actual file path
            net = loadedModel.net; % Make sure to use the correct variable name
            inputSize = [224, 224];    
        case 'AlexNet-Sgdm'
            % Load your pre-trained AlexNet model
           loadedModel = load('C:\Users\İREM\Desktop\derin_ogrenme_arttirilmis\veri_artırılımıs_alexnet_sgdm_30.mat');  % Update with your actual file path
            net = loadedModel.net; % Make sure to use the correct variable name
            inputSize = [227, 227];
            disp(loadedModel);
        case 'AlexNet-Adam'
            % Load your pre-trained AlexNet model
            loadedModel = load('C:\Users\İREM\Desktop\derin_ogrenme_arttirilmis\veri_artırılımıs_alexnet_adam_30.mat');% Update with your actual file path
            net = loadedModel.net; % Make sure to use the correct variable name
            inputSize = [227, 227];
        case 'AlexNet-Rmsprop'
            % Load your pre-trained AlexNet model
            loadedModel = load('C:\Users\İREM\Desktop\derin_ogrenme_arttirilmis\alexnetrmsproparttirilmis.mat'); % Update with your actual file path
            net = loadedModel.net; % Make sure to use the correct variable name
            inputSize = [227, 227];    
        case 'MobileNet-Adam'
            % Load or create MobileNet model
            loadedModel = load('C:\Users\İREM\Desktop\derin_ogrenme_arttirilmis\Mobilenetadamarttirilmis.mat'); % Update with your actual file path
            net = loadedModel.net; % Make sure to use the correct variable name
            inputSize = [224, 224];
        case 'MobileNet-Sgdm'
            % Load or create MobileNet model
            loadedModel = load('C:\Users\İREM\Desktop\derin_ogrenme_arttirilmis\Mobilenetsgdmarttirilmis.mat'); % Update with your actual file path
            net = loadedModel.net; % Make sure to use the correct variable name
            inputSize = [224, 224];
        case 'MobileNet-Rmsprop'
            % Load or create MobileNet model
           loadedModel = load('C:\Users\İREM\Desktop\derin_ogrenme_arttirilmis\Mobilenetrmsproparttirilmis.mat'); % Update with your actual file path
            net = loadedModel.net; % Make sure to use the correct variable name
            inputSize = [224, 224];    
        case 'MyCNN-Adam'
            % Load or create MobileNet model
            loadedModel = load('C:\Users\İREM\Desktop\derin_ogrenme_arttirilmis\mycnnadamartirilmis.mat'); % Update with your actual file path
            net = loadedModel.net; % Make sure to use the correct variable name
            inputSize = [224, 224];
        case 'MyCNN-Sgdm'
            % Load or create MobileNet model
            loadedModel = load('C:\Users\İREM\Desktop\derin_ogrenme_arttirilmis\mycnnsgdmartirilmis.mat'); % Update with your actual file path
            net = loadedModel.net; % Make sure to use the correct variable name
            inputSize = [224, 224];
        case 'MyCNN-Rmsprop'
            % Load or create MobileNet model
           loadedModel = load('C:\Users\İREM\Desktop\derin_ogrenme_arttirilmis\mycnnrmspropartirilmis.mat'); % Update with your actual file path
            net = loadedModel.net; % Make sure to use the correct variable name
            inputSize = [224, 224];     
        otherwise
            disp('Desteklenmeyen bir model seçildi.');
            return;
    end
    % Preprocess the image
    img = imread(app.filePath);
    img = imresize(img, inputSize); % Adjust the size based on the selected model

    % Perform classification using the loaded model
    predictedLabel = classify(net, img);

    % Display the result in a label (replace 'YourLabel' with your actual label component)
    app.TanLabel.Text = [ 'Tanı: ',char(predictedLabel)];


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.liveImg = uiaxes(app.UIFigure);
            app.liveImg.XTick = [];
            app.liveImg.YTick = [];
            app.FotorafSeButton = uibutton(app.UIFigure, 'push');
            app.FotorafSeButton.ButtonPushedFcn = createCallbackFcn(app, @FotorafSeButtonPushed, true);
            app.FotorafSeButton.Text = 'Fotoğraf Seç';
            app.ModelSeDropDownLabel = uilabel(app.UIFigure);
            app.ModelSeDropDownLabel.Text = 'Model Seç';
            app.ModelSeDropDown = uidropdown(app.UIFigure);
            app.ModelSeDropDown.Items = {'GoogleNet-Adam', 'GoogleNet-Sgdm', 'GoogleNet-Rmsprop', 'AlexNet-Adam', 'AlexNet-Sgdm', 'AlexNet-Rmsprop', 'MobileNet-Adam', 'MobileNet-Sgdm', 'MobileNet-Rmsprop', 'MyCNN-Adam', 'MyCNN-Sgdm', 'MyCNN-Rmsprop'};
            app.ModelSeDropDown.Value = 'GoogleNet-Adam';
            app.SnflandrButton = uibutton(app.UIFigure, 'push');
            app.SnflandrButton.ButtonPushedFcn = createCallbackFcn(app, @SnflandrButtonPushed, true);
            app.SnflandrButton.Text = 'Sınıflandır';
            app.TanLabel = uilabel(app.UIFigure);
            app.TanLabel.Text = 'Tanı:';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Adsız tasarım2.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'memekanseri5.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deneme2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end